classdef FilterApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        ApproxDropDown         matlab.ui.control.DropDown
        ApproxDropDownLabel    matlab.ui.control.Label
        FreqTypeDropDown       matlab.ui.control.DropDown
        FreqTypeDropDownLabel  matlab.ui.control.Label
        WcSlider               matlab.ui.control.Slider
        WcLabel                matlab.ui.control.Label
        AmaxSlider             matlab.ui.control.Slider
        AmaxSliderLabel        matlab.ui.control.Label
        WsSlider_              matlab.ui.control.Slider
        WsSliderLabel          matlab.ui.control.Label
        AminSlider             matlab.ui.control.Slider
        AminSliderLabel        matlab.ui.control.Label
        OrderSpinner           matlab.ui.control.Spinner
        OrderSpinnerLabel      matlab.ui.control.Label
        PHASE_VS_W             matlab.ui.control.UIAxes
        ZPplot                 matlab.ui.control.UIAxes
        AMP_VS_W               matlab.ui.control.UIAxes
        AMP_in_S_plane         matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        
        function plot_PoleTransZero_APP(app,poles, zeros, K)
            %UNTITLED2 此处显示有关此函数的摘要
            %   此处显示详细说明

            Step = 0.01;
            Wlim = 5;

            RespWlim = 100;

            % 1. 创建网格
            [sigma, omege] = meshgrid(-Wlim:Step:0, -Wlim:Step:Wlim);

            H = K;
            % 2. 计算Z值
            if ~isempty(poles)
                for i = 1:length(poles)
                    H = H .* (1./((sigma + omege * 1i)-poles(i)));
                end
            end

            if ~isempty(zeros)
                for i= 1:length(zeros)
                    H = H.* ((sigma + omege * 1i)-zeros(i));
                end
            end

            Z = abs(H);

            Z = min(1e2,Z);

            % 3. 绘制曲面

            mesh(app.AMP_in_S_plane,sigma, omege, Z);
            set(app.AMP_in_S_plane, 'ZScale', 'log')
            set(app.AMP_in_S_plane, 'ColorScale', 'log')
            % zlim([0,25])
            title(app.AMP_in_S_plane,'z = |H(s)|');
            xlabel(app.AMP_in_S_plane,'Re'); ylabel(app.AMP_in_S_plane,'Im'); zlabel(app.AMP_in_S_plane,'|H(s)|');
            colormap(app.AMP_in_S_plane,"jet"); % 设置颜色
            colorbar(app.AMP_in_S_plane);        % 显示颜色栏
            shading(app.AMP_in_S_plane,"flat");   % 平滑颜色过渡
            % daspect([1, 1 ,5]);


            omega = 0:Step:RespWlim;
            H = K;
            % 2. 计算Z值
            if ~isempty(poles)
                for i = 1:length(poles)
                    H = H .* (1./(omega * 1i-poles(i)));
                end
            end

            if ~isempty(zeros)
                for i= 1:length(zeros)
                    H = H .* ((omega * 1i)-zeros(i));
                end
            end


            semilogx(app.AMP_VS_W, omega, 20*log10(abs(H)))
            grid(app.AMP_VS_W, "on");
            title(app.AMP_VS_W, "Magnitude VS. Angular Frequency")
            xlabel(app.AMP_VS_W,'\omega/(rad/s)');
            ylabel(app.AMP_VS_W,'dB');
            
            % phase_resp = angle(H);

            % 处理相位缠绕问题（解决超过-2π的跳跃）
            % unwrapped_phase = unwrap(phase_resp);
            % 
            % % 计算群延时 = -d(相位)/d(角频率) [单位：秒]
            % group_delay = -diff(unwrapped_phase) ./ diff(omega);
            % 
            % % 为保持长度一致，在群延时末尾复制最后一个值
            % group_delay = [group_delay, group_delay(end)];

            Fsys = zpk(zeros, poles, K);
            t = 0:0.1:20;
            [y, t] = step(Fsys, t);

            % semilogx(app.PHASE_VS_W, omega, unwrapped_phase);
            plot(app.PHASE_VS_W, t, y*8);
            grid(app.PHASE_VS_W, "on");
            title(app.PHASE_VS_W, "Step Response")
            xlabel(app.PHASE_VS_W,'t/s');
            ylabel(app.PHASE_VS_W,'Out');

            pzlim = 3;

            scatter(app.ZPplot, real(poles), imag(poles),'x','LineWidth',2);
            hold(app.ZPplot, 'on')
            scatter(app.ZPplot, real(zeros), imag(zeros),'ok','LineWidth',2);
            grid(app.ZPplot, "on");
            xlim(app.ZPplot, [-2*pzlim,0]);
            ylim(app.ZPplot, [-pzlim,pzlim]);
            t = pi/2:0.01:3*pi/2;
            plot(app.ZPplot, cos(t), sin(t), '--')
            daspect(app.ZPplot, [1, 1 ,1]);
            title(app.ZPplot,'pzmap');
            xlabel(app.ZPplot,'Re'); 
            ylabel(app.ZPplot,'Im');
            hold(app.ZPplot, 'off');
        end

        function [zH, pH, k] = MYLP2HP(~,z,p,k)
            %UNTITLED 此处显示有关此函数的摘要
            %   此处显示详细说明
            pH = 1./p;
            k = k/prod(p);

            if ~isempty(z)
                zH = (1./z)';
                k = k*prod(z);
            else
                zH = [];
            end
            if length(z)<length(p)
                zH = [zH, zeros(1,length(p)-length(z))]';
            end
        end
        
        function plotFilter(app)
            Wc = app.WcSlider.Value;
            Ws = app.WsSlider_.Value;
            Amax = app.AmaxSlider.Value;
            Amin = app.AminSlider.Value;
            N = app.OrderSpinner.Value;
            FilterType = app.FreqTypeDropDown.Value;
            Approx = app.ApproxDropDown.Value;

            switch Approx
                case "Butterworth"
                    [b, a] = butter(N, Wc, 'low','s');
                case "Chebychev I"
                    [b, a] = cheby1(N, Amax, Wc, 'low','s');
                case "Chebychev II"
                    [b, a] = cheby2(N, Amin, Ws, 'low', 's');
                case "Elliptic"
                    [b, a] = ellip(N, Amax, Amin, Wc, 'low','s');
                case "Bessel"
                    [b, a] = besself(N,Wc,"low");
            end

            [z,p,k] = tf2zp(b,a);

            if FilterType == "HighPass"
                [z,p,k] = MYLP2HP(app, z, p, k);
            end

            plot_PoleTransZero_APP(app,p,z,k)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: OrderSpinner
        function OrderSpinnerValueChanged(app, event)
            plotFilter(app);
            
        end

        % Callback function
        function WsSliderValueChanged(app, event)
            plotFilter(app);
            
            
        end

        % Value changed function: AminSlider
        function AminSliderValueChanged(app, event)
            plotFilter(app);
            
        end

        % Callback function
        function WsSliderValueChanged2(app, event)
            plotFilter(app);
            
        end

        % Value changed function: WsSlider_
        function WsSlider_ValueChanged(app, event)
            plotFilter(app);
            
        end

        % Value changed function: WcSlider
        function WcSliderValueChanged(app, event)
            
            plotFilter(app);
        end

        % Value changed function: AmaxSlider
        function AmaxSliderValueChanged(app, event)
            plotFilter(app);
            
        end

        % Value changed function: FreqTypeDropDown
        function FreqTypeDropDownValueChanged(app, event)
            plotFilter(app);
            
        end

        % Value changed function: ApproxDropDown
        function ApproxDropDownValueChanged(app, event)
            plotFilter(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AMP_in_S_plane = uiaxes(app.UIFigure);
            title(app.AMP_in_S_plane, 'Z = |H(s)|')
            xlabel(app.AMP_in_S_plane, 'X')
            ylabel(app.AMP_in_S_plane, 'Y')
            zlabel(app.AMP_in_S_plane, 'Z')
            app.AMP_VS_W = uiaxes(app.UIFigure);
            title(app.AMP_VS_W, 'Z = |H(s)|')
            xlabel(app.AMP_VS_W, 'X')
            ylabel(app.AMP_VS_W, 'Y')
            zlabel(app.AMP_VS_W, 'Z')
            app.ZPplot = uiaxes(app.UIFigure);
            title(app.ZPplot, 'Z = |H(s)|')
            xlabel(app.ZPplot, 'X')
            ylabel(app.ZPplot, 'Y')
            zlabel(app.ZPplot, 'Z')
            app.PHASE_VS_W = uiaxes(app.UIFigure);
            title(app.PHASE_VS_W, 'Z = |H(s)|')
            xlabel(app.PHASE_VS_W, 'X')
            ylabel(app.PHASE_VS_W, 'Y')
            zlabel(app.PHASE_VS_W, 'Z')
            app.OrderSpinnerLabel = uilabel(app.UIFigure);
            app.OrderSpinnerLabel.Text = 'Order';
            app.OrderSpinner = uispinner(app.UIFigure);
            app.OrderSpinner.Limits = [2 Inf];
            app.OrderSpinner.ValueChangedFcn = createCallbackFcn(app, @OrderSpinnerValueChanged, true);
            app.OrderSpinner.Value = 2;
            app.AminSliderLabel = uilabel(app.UIFigure);
            app.AminSliderLabel.Text = 'Amin';
            app.AminSlider = uislider(app.UIFigure);
            app.AminSlider.ValueChangedFcn = createCallbackFcn(app, @AminSliderValueChanged, true);
            app.AminSlider.Value = 10;
            app.WsSliderLabel = uilabel(app.UIFigure);
            app.WsSliderLabel.Text = 'Ws';
            app.WsSlider_ = uislider(app.UIFigure);
            app.WsSlider_.Limits = [1 10];
            app.WsSlider_.ValueChangedFcn = createCallbackFcn(app, @WsSlider_ValueChanged, true);
            app.WsSlider_.Value = 2;
            app.AmaxSliderLabel = uilabel(app.UIFigure);
            app.AmaxSliderLabel.Text = 'Amax';
            app.AmaxSlider = uislider(app.UIFigure);
            app.AmaxSlider.Limits = [0.01 6];
            app.AmaxSlider.ValueChangedFcn = createCallbackFcn(app, @AmaxSliderValueChanged, true);
            app.AmaxSlider.Value = 1;
            app.WcLabel = uilabel(app.UIFigure);
            app.WcLabel.Text = 'Wc';
            app.WcSlider = uislider(app.UIFigure);
            app.WcSlider.Limits = [0 1];
            app.WcSlider.ValueChangedFcn = createCallbackFcn(app, @WcSliderValueChanged, true);
            app.WcSlider.Value = 1;
            app.FreqTypeDropDownLabel = uilabel(app.UIFigure);
            app.FreqTypeDropDownLabel.Text = 'FreqType';
            app.FreqTypeDropDown = uidropdown(app.UIFigure);
            app.FreqTypeDropDown.Items = {'LowPass', 'HighPass'};
            app.FreqTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @FreqTypeDropDownValueChanged, true);
            app.FreqTypeDropDown.Value = 'LowPass';
            app.ApproxDropDownLabel = uilabel(app.UIFigure);
            app.ApproxDropDownLabel.Text = 'Approx';
            app.ApproxDropDown = uidropdown(app.UIFigure);
            app.ApproxDropDown.Items = {'Butterworth', 'Bessel', 'Chebychev I', 'Chebychev II', 'Elliptic'};
            app.ApproxDropDown.ValueChangedFcn = createCallbackFcn(app, @ApproxDropDownValueChanged, true);
            app.ApproxDropDown.Value = 'Butterworth';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FilterApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end